classdef Bernsley_fern < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        Menu_2                        matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        ReturntomenuButton            matlab.ui.control.Button
        Slider_a1                     matlab.ui.control.Slider
        MovingrightleavesSliderLabel  matlab.ui.control.Label
        Slider_a2                     matlab.ui.control.Slider
        MovingleftleavesSliderLabel   matlab.ui.control.Label
        Slider_a3                     matlab.ui.control.Slider
        ChangingthetiltofafernSliderLabel  matlab.ui.control.Label
        Slider_a4                     matlab.ui.control.Slider
        TuringstemsintoleavesSliderLabel  matlab.ui.control.Label
        ExamplesofBernsleyfernsDropDown  matlab.ui.control.DropDown
        ExamplesofBernsleyfernsLabel  matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        SaveimageButton               matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        ResetslidersButton            matlab.ui.control.Button
        p4                            matlab.ui.control.NumericEditField
        p4EditFieldLabel              matlab.ui.control.Label
        p3                            matlab.ui.control.NumericEditField
        p3EditFieldLabel              matlab.ui.control.Label
        p2                            matlab.ui.control.NumericEditField
        p2EditFieldLabel              matlab.ui.control.Label
        p1                            matlab.ui.control.NumericEditField
        p1EditFieldLabel              matlab.ui.control.Label
        NumberofiterationsEditField   matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        xyLabel_4                     matlab.ui.control.Label
        Label_36                      matlab.ui.control.Label
        Label_35                      matlab.ui.control.Label
        Label_34                      matlab.ui.control.Label
        Label_33                      matlab.ui.control.Label
        Label_32                      matlab.ui.control.Label
        Label_31                      matlab.ui.control.Label
        Label_30                      matlab.ui.control.Label
        Label_29                      matlab.ui.control.Label
        Label_28                      matlab.ui.control.Label
        f_4xyLabel                    matlab.ui.control.Label
        f4                            matlab.ui.control.NumericEditField
        e4                            matlab.ui.control.NumericEditField
        d4                            matlab.ui.control.NumericEditField
        b4                            matlab.ui.control.NumericEditField
        c4                            matlab.ui.control.NumericEditField
        a4                            matlab.ui.control.NumericEditField
        xyLabel_3                     matlab.ui.control.Label
        Label_27                      matlab.ui.control.Label
        Label_26                      matlab.ui.control.Label
        Label_25                      matlab.ui.control.Label
        Label_24                      matlab.ui.control.Label
        Label_23                      matlab.ui.control.Label
        Label_22                      matlab.ui.control.Label
        Label_21                      matlab.ui.control.Label
        Label_20                      matlab.ui.control.Label
        Label_19                      matlab.ui.control.Label
        f_3xyLabel                    matlab.ui.control.Label
        f3                            matlab.ui.control.NumericEditField
        e3                            matlab.ui.control.NumericEditField
        d3                            matlab.ui.control.NumericEditField
        b3                            matlab.ui.control.NumericEditField
        c3                            matlab.ui.control.NumericEditField
        a3                            matlab.ui.control.NumericEditField
        xyLabel_2                     matlab.ui.control.Label
        Label_18                      matlab.ui.control.Label
        Label_17                      matlab.ui.control.Label
        Label_16                      matlab.ui.control.Label
        Label_15                      matlab.ui.control.Label
        Label_14                      matlab.ui.control.Label
        Label_13                      matlab.ui.control.Label
        Label_12                      matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        f_2xyLabel                    matlab.ui.control.Label
        f2                            matlab.ui.control.NumericEditField
        e2                            matlab.ui.control.NumericEditField
        d2                            matlab.ui.control.NumericEditField
        b2                            matlab.ui.control.NumericEditField
        c2                            matlab.ui.control.NumericEditField
        a2                            matlab.ui.control.NumericEditField
        xyLabel                       matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        f_1xyLabel                    matlab.ui.control.Label
        f1                            matlab.ui.control.NumericEditField
        e1                            matlab.ui.control.NumericEditField
        d1                            matlab.ui.control.NumericEditField
        b1                            matlab.ui.control.NumericEditField
        c1                            matlab.ui.control.NumericEditField
        a1                            matlab.ui.control.NumericEditField
        Barnsley_plot                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updatePlot(app)
            x = 0;
            y = 0;
            max_iter = app.NumberofiterationsEditField.Value;
            X = zeros(1, max_iter);
            Y = zeros(1, max_iter);
        
            % Odczytaj współczynniki z pól edycyjnych
            a1 = app.a1.Value;
            b1 = app.b1.Value;
            c1 = app.c1.Value;
            d1 = app.d1.Value;
            e1 = app.e1.Value;
            f1 = app.f1.Value;
            p1 = app.p1.Value;
        
            a2 = app.a2.Value;
            b2 = app.b2.Value;
            c2 = app.c2.Value;
            d2 = app.d2.Value;
            e2 = app.e2.Value;
            f2 = app.f2.Value;
            p2 = app.p2.Value;
        
            a3 = app.a3.Value;
            b3 = app.b3.Value;
            c3 = app.c3.Value;
            d3 = app.d3.Value;
            e3 = app.e3.Value;
            f3 = app.f3.Value;
            p3 = app.p3.Value;
        
            a4 = app.a4.Value;
            b4 = app.b4.Value;
            c4 = app.c4.Value;
            d4 = app.d4.Value;
            e4 = app.e4.Value;
            f4 = app.f4.Value;
            p4 = app.p4.Value;
        
            % Wyczyść poprzedni wykres
            cla(app.Barnsley_plot);
        
            % Oblicz punkty iteracyjnie
            for iter = 1:max_iter
                p = rand;
                if p < p1
                    xn = a1 * x + b1 * y + e1;
                    yn = c1 * x + d1 * y + f1;
                elseif p < p1+p2
                    xn = a2 * x + b2 * y + e2;
                    yn = c2 * x + d2 * y + f2;
                elseif p < p1+p2+p3
                    xn = a3 * x + b3 * y + e3;
                    yn = c3 * x + d3 * y + f3;
                else
                    xn = a4 * x + b4 * y + e4;
                    yn = c4 * x + d4 * y + f4;
                end
                X(iter) = xn;
                Y(iter) = yn;
                x = xn;
                y = yn;
            end
        
            % Narysuj punkty
            scatter(app.Barnsley_plot, X, Y, 'g.', 'SizeData', 1);
            drawnow;


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.updatePlot()
        end

        % Value changed function: ExamplesofBernsleyfernsDropDown
        function ExamplesofBernsleyfernsDropDownValueChanged(app, event)
            value = app.ExamplesofBernsleyfernsDropDown.Value;
            switch value
                case 'Oryginal'
                    app.a1.Value = 0.0;
                    app.b1.Value = 0.0;
                    app.c1.Value = 0.0;
                    app.d1.Value = 0.16;
                    app.e1.Value = 0.0;
                    app.f1.Value = 0.0;
                    app.p1.Value = 0.01;
        
                    app.a2.Value = 0.85;
                    app.b2.Value = 0.04;
                    app.c2.Value = -0.04;
                    app.d2.Value = 0.85;
                    app.e2.Value = 0.0;
                    app.f2.Value = 1.6;
                    app.p2.Value = 0.85;
        
                    app.a3.Value = 0.2;
                    app.b3.Value = -0.26;
                    app.c3.Value = 0.23;
                    app.d3.Value = 0.22;
                    app.e3.Value = 0.0;
                    app.f3.Value = 1.6;
                    app.p3.Value = 0.07;
        
                    app.a4.Value = -0.15;
                    app.b4.Value = 0.28;
                    app.c4.Value = 0.26;
                    app.d4.Value = 0.24;
                    app.e4.Value = 0.0;
                    app.f4.Value = 0.44;
                    app.p4.Value = 0.07;
        
                case 'Cyclosorus'
                    app.a1.Value = 0.0;
                    app.b1.Value = 0.0;
                    app.c1.Value = 0.0;
                    app.d1.Value = 0.25;
                    app.e1.Value = 0.0;
                    app.f1.Value = -0.4;
                    app.p1.Value = 0.02;
        
                    app.a2.Value = 0.95;
                    app.b2.Value = 0.005;
                    app.c2.Value = -0.005;
                    app.d2.Value = 0.93;
                    app.e2.Value = -0.002;
                    app.f2.Value = 0.5;
                    app.p2.Value = 0.84;
        
                    app.a3.Value = 0.035;
                    app.b3.Value = -0.2;
                    app.c3.Value = 0.16;
                    app.d3.Value = 0.04;
                    app.e3.Value = -0.09;
                    app.f3.Value = 0.02;
                    app.p3.Value = 0.07;
        
                    app.a4.Value = -0.04;
                    app.b4.Value = 0.2;
                    app.c4.Value = 0.16;
                    app.d4.Value = 0.04;
                    app.e4.Value = 0.083;
                    app.f4.Value = 0.12;
                    app.p4.Value = 0.07;
        
                case 'Culcita'
                    app.a1.Value = 0.0;
                    app.b1.Value = 0.0;
                    app.c1.Value = 0.0;
                    app.d1.Value = 0.25;
                    app.e1.Value = 0.0;
                    app.f1.Value = -0.14;
                    app.p1.Value = 0.02;
        
                    app.a2.Value = 0.85;
                    app.b2.Value = 0.02;
                    app.c2.Value = -0.02;
                    app.d2.Value = 0.83;
                    app.e2.Value = 0.0;
                    app.f2.Value = 1.0;
                    app.p2.Value = 0.84;
        
                    app.a3.Value = 0.09;
                    app.b3.Value = -0.28;
                    app.c3.Value = 0.3;
                    app.d3.Value = 0.11;
                    app.e3.Value = 0.0;
                    app.f3.Value = 0.6;
                    app.p3.Value = 0.07;
        
                    app.a4.Value = -0.09;
                    app.b4.Value = 0.28;
                    app.c4.Value = 0.3;
                    app.d4.Value = 0.09;
                    app.e4.Value = 0.0;
                    app.f4.Value = 0.7;
                    app.p4.Value = 0.07;
        
                case 'Fractal tree'
                    app.a1.Value = 0.0;
                    app.b1.Value = 0.0;
                    app.c1.Value = 0.0;
                    app.d1.Value = 0.5;
                    app.e1.Value = 0.0;
                    app.f1.Value = 0.0;
                    app.p1.Value = 0.05;
        
                    app.a2.Value = 0.42;
                    app.b2.Value = -0.42;
                    app.c2.Value = 0.42;
                    app.d2.Value = 0.42;
                    app.e2.Value = 0.0;
                    app.f2.Value = 0.2;
                    app.p2.Value = 0.4;
        
                    app.a3.Value = 0.42;
                    app.b3.Value = 0.42;
                    app.c3.Value = -0.42;
                    app.d3.Value = 0.42;
                    app.e3.Value = 0.0;
                    app.f3.Value = 0.2;
                    app.p3.Value = 0.4;
        
                    app.a4.Value = 0.1;
                    app.b4.Value = 0.0;
                    app.c4.Value = 0.0;
                    app.d4.Value = 0.1;
                    app.e4.Value = 0.0;
                    app.f4.Value = 0.2;
                    app.p4.Value = 0.15;
            end
        end

        % Value changing function: Slider_a4
        function Slider_a4ValueChanging(app, event)
            changingValue = event.Value;
            app.a1.Value = changingValue;
            app.updatePlot();
        end

        % Value changing function: Slider_a3
        function Slider_a3ValueChanging(app, event)
            changingValue = event.Value;
            app.b2.Value = changingValue;
            app.updatePlot();
        end

        % Value changing function: Slider_a2
        function Slider_a2ValueChanging(app, event)
            changingValue = event.Value;
            app.e3.Value = changingValue;
            app.updatePlot();
        end

        % Value changing function: Slider_a1
        function Slider_a1ValueChanging(app, event)
            changingValue = event.Value;
            app.e4.Value = changingValue;
            app.updatePlot();
        end

        % Button pushed function: ResetslidersButton
        function ResetslidersButtonPushed(app, event)

            app.Slider_a1.Value = 0;
            app.a1.Value=0;
            app.Slider_a2.Value = 0;
            app.e3.Value=0;
            app.Slider_a3.Value = 0.04;
            app.b2.Value=0.04;
            app.Slider_a4.Value = 0;
            app.e4.Value=0;
            app.updatePlot();
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            [file, path] = uiputfile({'*.png';'*.jpg';'*.pdf';'*.fig'}, 'Zapisz wykres jako');
            if isequal(file, 0) || isequal(path, 0)
                return;
            else
                filename = fullfile(path, file);
                try
                    exportgraphics(app.Barnsley_plot, filename);
                catch
                    saveas(app.Barnsley_plot, filename);
                end
            end
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            
        end

        % Button pushed function: ReturntomenuButton
        function ReturntomenuButtonPushed(app, event)
            FractalMenuApp; 
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Barnsley_plot = uiaxes(app.GridLayout);
            title(app.Barnsley_plot, 'Bernsley fern')
            xlabel(app.Barnsley_plot, 'X')
            ylabel(app.Barnsley_plot, 'Y')
            zlabel(app.Barnsley_plot, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.a1 = uieditfield(app.Panel, 'numeric');
            app.a1.Limits = [-1 1];
            app.a1.ValueDisplayFormat = '%.3f';
            app.c1 = uieditfield(app.Panel, 'numeric');
            app.c1.Limits = [-1 1];
            app.c1.ValueDisplayFormat = '%.3f';
            app.b1 = uieditfield(app.Panel, 'numeric');
            app.b1.Limits = [-1 1];
            app.b1.ValueDisplayFormat = '%.3f';
            app.d1 = uieditfield(app.Panel, 'numeric');
            app.d1.Limits = [-1 1];
            app.d1.ValueDisplayFormat = '%.3f';
            app.d1.Value = 0.16;
            app.e1 = uieditfield(app.Panel, 'numeric');
            app.e1.Limits = [-2 2];
            app.e1.ValueDisplayFormat = '%.2f';
            app.f1 = uieditfield(app.Panel, 'numeric');
            app.f1.Limits = [-2 2];
            app.f1.ValueDisplayFormat = '%.2f';
            app.f_1xyLabel = uilabel(app.Panel);
            app.f_1xyLabel.Text = 'f_1(x,y)=';
            app.Label = uilabel(app.Panel);
            app.Label.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = ',';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = ',';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '+';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.xyLabel = uilabel(app.Panel);
            app.xyLabel.Text = {'x'; ''; 'y'};
            app.a2 = uieditfield(app.Panel, 'numeric');
            app.a2.Limits = [-1 1];
            app.a2.ValueDisplayFormat = '%.3f';
            app.a2.Value = 0.86;
            app.c2 = uieditfield(app.Panel, 'numeric');
            app.c2.Limits = [-1 1];
            app.c2.ValueDisplayFormat = '%.3f';
            app.c2.Value = -0.04;
            app.b2 = uieditfield(app.Panel, 'numeric');
            app.b2.Limits = [-1 1];
            app.b2.ValueDisplayFormat = '%.3f';
            app.b2.Value = 0.04;
            app.d2 = uieditfield(app.Panel, 'numeric');
            app.d2.Limits = [-1 1];
            app.d2.ValueDisplayFormat = '%.3f';
            app.d2.Value = 0.85;
            app.e2 = uieditfield(app.Panel, 'numeric');
            app.e2.Limits = [-2 2];
            app.e2.ValueDisplayFormat = '%.2f';
            app.f2 = uieditfield(app.Panel, 'numeric');
            app.f2.Limits = [-2 2];
            app.f2.ValueDisplayFormat = '%.2f';
            app.f2.Value = 1.6;
            app.f_2xyLabel = uilabel(app.Panel);
            app.f_2xyLabel.Text = 'f_2(x,y)=';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = ',';
            app.Label_12 = uilabel(app.Panel);
            app.Label_12.Text = ',';
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.Label_15 = uilabel(app.Panel);
            app.Label_15.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_16 = uilabel(app.Panel);
            app.Label_16.Text = '+';
            app.Label_17 = uilabel(app.Panel);
            app.Label_17.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_18 = uilabel(app.Panel);
            app.Label_18.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.xyLabel_2 = uilabel(app.Panel);
            app.xyLabel_2.Text = {'x'; ''; 'y'};
            app.a3 = uieditfield(app.Panel, 'numeric');
            app.a3.Limits = [-1 1];
            app.a3.ValueDisplayFormat = '%.3f';
            app.a3.Value = 0.2;
            app.c3 = uieditfield(app.Panel, 'numeric');
            app.c3.Limits = [-1 1];
            app.c3.ValueDisplayFormat = '%.3f';
            app.c3.Value = 0.23;
            app.b3 = uieditfield(app.Panel, 'numeric');
            app.b3.Limits = [-1 1];
            app.b3.ValueDisplayFormat = '%.3f';
            app.b3.Value = -0.26;
            app.d3 = uieditfield(app.Panel, 'numeric');
            app.d3.Limits = [-1 1];
            app.d3.ValueDisplayFormat = '%.3f';
            app.d3.Value = 0.22;
            app.e3 = uieditfield(app.Panel, 'numeric');
            app.e3.Limits = [-2 2];
            app.e3.ValueDisplayFormat = '%.2f';
            app.f3 = uieditfield(app.Panel, 'numeric');
            app.f3.Limits = [-2 2];
            app.f3.ValueDisplayFormat = '%.2f';
            app.f3.Value = 1.6;
            app.f_3xyLabel = uilabel(app.Panel);
            app.f_3xyLabel.Text = 'f_3(x,y)=';
            app.Label_19 = uilabel(app.Panel);
            app.Label_19.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.Label_20 = uilabel(app.Panel);
            app.Label_20.Text = ',';
            app.Label_21 = uilabel(app.Panel);
            app.Label_21.Text = ',';
            app.Label_22 = uilabel(app.Panel);
            app.Label_22.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_23 = uilabel(app.Panel);
            app.Label_23.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.Label_24 = uilabel(app.Panel);
            app.Label_24.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_25 = uilabel(app.Panel);
            app.Label_25.Text = '+';
            app.Label_26 = uilabel(app.Panel);
            app.Label_26.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_27 = uilabel(app.Panel);
            app.Label_27.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.xyLabel_3 = uilabel(app.Panel);
            app.xyLabel_3.Text = {'x'; ''; 'y'};
            app.a4 = uieditfield(app.Panel, 'numeric');
            app.a4.Limits = [-1 1];
            app.a4.ValueDisplayFormat = '%.3f';
            app.a4.Value = -0.15;
            app.c4 = uieditfield(app.Panel, 'numeric');
            app.c4.Limits = [-1 1];
            app.c4.ValueDisplayFormat = '%.3f';
            app.c4.Value = 0.26;
            app.b4 = uieditfield(app.Panel, 'numeric');
            app.b4.Limits = [-1 1];
            app.b4.ValueDisplayFormat = '%.3f';
            app.b4.Value = 0.28;
            app.d4 = uieditfield(app.Panel, 'numeric');
            app.d4.Limits = [-1 1];
            app.d4.ValueDisplayFormat = '%.3f';
            app.d4.Value = 0.24;
            app.e4 = uieditfield(app.Panel, 'numeric');
            app.e4.Limits = [-2 2];
            app.e4.ValueDisplayFormat = '%.2f';
            app.f4 = uieditfield(app.Panel, 'numeric');
            app.f4.Limits = [-2 2];
            app.f4.ValueDisplayFormat = '%.2f';
            app.f4.Value = 0.44;
            app.f_4xyLabel = uilabel(app.Panel);
            app.f_4xyLabel.Text = 'f_4(x,y)=';
            app.Label_28 = uilabel(app.Panel);
            app.Label_28.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.Label_29 = uilabel(app.Panel);
            app.Label_29.Text = ',';
            app.Label_30 = uilabel(app.Panel);
            app.Label_30.Text = ',';
            app.Label_31 = uilabel(app.Panel);
            app.Label_31.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_32 = uilabel(app.Panel);
            app.Label_32.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.Label_33 = uilabel(app.Panel);
            app.Label_33.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_34 = uilabel(app.Panel);
            app.Label_34.Text = '+';
            app.Label_35 = uilabel(app.Panel);
            app.Label_35.Text = {''; ''; '⎤'; '⎥'; '⎥'; '⎦'};
            app.Label_36 = uilabel(app.Panel);
            app.Label_36.Text = {''; ''; '⎡'; '⎢'; '⎢'; '⎣'};
            app.xyLabel_4 = uilabel(app.Panel);
            app.xyLabel_4.Text = {'x'; ''; 'y'};
            app.NumberofiterationsEditFieldLabel = uilabel(app.Panel);
            app.NumberofiterationsEditFieldLabel.Text = ' Number of iterations';
            app.NumberofiterationsEditField = uieditfield(app.Panel, 'numeric');
            app.NumberofiterationsEditField.Limits = [0 Inf];
            app.NumberofiterationsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofiterationsEditField.Value = 20000;
            app.p1EditFieldLabel = uilabel(app.Panel);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1 = uieditfield(app.Panel, 'numeric');
            app.p1.Value = 0.01;
            app.p2EditFieldLabel = uilabel(app.Panel);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2 = uieditfield(app.Panel, 'numeric');
            app.p2.Value = 0.85;
            app.p3EditFieldLabel = uilabel(app.Panel);
            app.p3EditFieldLabel.Text = 'p3';
            app.p3 = uieditfield(app.Panel, 'numeric');
            app.p3.Value = 0.07;
            app.p4EditFieldLabel = uilabel(app.Panel);
            app.p4EditFieldLabel.Text = 'p4';
            app.p4 = uieditfield(app.Panel, 'numeric');
            app.p4.Value = 0.07;
            app.ResetslidersButton = uibutton(app.Panel, 'push');
            app.ResetslidersButton.ButtonPushedFcn = createCallbackFcn(app, @ResetslidersButtonPushed, true);
            app.ResetslidersButton.Text = 'Reset sliders';
            app.SaveimageButton = uibutton(app.GridLayout, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Text = 'Save image';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ExamplesofBernsleyfernsLabel = uilabel(app.GridLayout);
            app.ExamplesofBernsleyfernsLabel.Text = {'Examples of'; ' Bernsley ferns'};
            app.ExamplesofBernsleyfernsDropDown = uidropdown(app.GridLayout);
            app.ExamplesofBernsleyfernsDropDown.Items = {'Oryginal', 'Cyclosorus', 'Culcita', 'Fractal tree'};
            app.ExamplesofBernsleyfernsDropDown.ValueChangedFcn = createCallbackFcn(app, @ExamplesofBernsleyfernsDropDownValueChanged, true);
            app.ExamplesofBernsleyfernsDropDown.Value = 'Oryginal';
            app.TuringstemsintoleavesSliderLabel = uilabel(app.GridLayout);
            app.TuringstemsintoleavesSliderLabel.Text = 'Turing stems into leaves';
            app.Slider_a4 = uislider(app.GridLayout);
            app.Slider_a4.Limits = [-0.5 0.5];
            app.Slider_a4.ValueChangingFcn = createCallbackFcn(app, @Slider_a4ValueChanging, true);
            app.ChangingthetiltofafernSliderLabel = uilabel(app.GridLayout);
            app.ChangingthetiltofafernSliderLabel.Text = 'Changing the tilt of a fern';
            app.Slider_a3 = uislider(app.GridLayout);
            app.Slider_a3.Limits = [-0.1 0.1];
            app.Slider_a3.ValueChangingFcn = createCallbackFcn(app, @Slider_a3ValueChanging, true);
            app.Slider_a3.Value = 0.04;
            app.MovingleftleavesSliderLabel = uilabel(app.GridLayout);
            app.MovingleftleavesSliderLabel.Text = 'Moving left leaves';
            app.Slider_a2 = uislider(app.GridLayout);
            app.Slider_a2.Limits = [-0.5 0.5];
            app.Slider_a2.ValueChangingFcn = createCallbackFcn(app, @Slider_a2ValueChanging, true);
            app.MovingrightleavesSliderLabel = uilabel(app.GridLayout);
            app.MovingrightleavesSliderLabel.Text = 'Moving right leaves';
            app.Slider_a1 = uislider(app.GridLayout);
            app.Slider_a1.Limits = [-0.5 0.5];
            app.Slider_a1.ValueChangingFcn = createCallbackFcn(app, @Slider_a1ValueChanging, true);
            app.ReturntomenuButton = uibutton(app.GridLayout, 'push');
            app.ReturntomenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntomenuButtonPushed, true);
            app.ReturntomenuButton.Text = 'Return to menu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bernsley_fern

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end