classdef FractalMenuApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        FormoreinformationsaboutJuliasetButton  matlab.ui.control.Button
        FormoreinformationsaboutMandelbrotsetButton  matlab.ui.control.Button
        FormoreinformationsaboutBernsleyFernButton  matlab.ui.control.Button
        JuliasetButton       matlab.ui.control.Button
        MandelbrotsetButton  matlab.ui.control.Button
        BernsleyFernButton   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BernsleyFernButton
        function BernsleyFernButtonPushed(app, event)
            %Opening Bernsley_fern app
            Bernsley_fern; 
            delete(app.UIFigure);
        end

        % Button pushed function: MandelbrotsetButton
        function MandelbrotsetButtonPushed(app, event)
            %Opening Mandelbrotset app
            MandelbrotSet;
            delete(app.UIFigure);
        end

        % Button pushed function: JuliasetButton
        function JuliasetButtonPushed(app, event)
            %Opening JuliaSet app
            JuliaSet;
            delete(app.UIFigure)
        end

        % Button pushed function: 
        % FormoreinformationsaboutBernsleyFernButton
        function FormoreinformationsaboutBernsleyFernButtonPushed(app, event)
            %Opening wikipedia about Bernsley_fern 
            web('https://en.wikipedia.org/wiki/Barnsley_fern', '-browser');
        end

        % Button pushed function: FormoreinformationsaboutJuliasetButton
        function FormoreinformationsaboutJuliasetButtonPushed(app, event)
            %Opening wikipedia about Julia Set
            web('https://en.wikipedia.org/wiki/Julia_set', '-browser');
        end

        % Button pushed function: 
        % FormoreinformationsaboutMandelbrotsetButton
        function FormoreinformationsaboutMandelbrotsetButtonPushed(app, event)
            %Opening wikipedia about Mandelbrot set 
            web('https://en.wikipedia.org/wiki/Mandelbrot_set', '-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BernsleyFernButton = uibutton(app.GridLayout, 'push');
            app.BernsleyFernButton.ButtonPushedFcn = createCallbackFcn(app, @BernsleyFernButtonPushed, true);
            app.BernsleyFernButton.Text = 'Bernsley  Fern';
            app.MandelbrotsetButton = uibutton(app.GridLayout, 'push');
            app.MandelbrotsetButton.ButtonPushedFcn = createCallbackFcn(app, @MandelbrotsetButtonPushed, true);
            app.MandelbrotsetButton.Text = 'Mandelbrot set';
            app.JuliasetButton = uibutton(app.GridLayout, 'push');
            app.JuliasetButton.ButtonPushedFcn = createCallbackFcn(app, @JuliasetButtonPushed, true);
            app.JuliasetButton.Text = 'Julia set';
            app.FormoreinformationsaboutBernsleyFernButton = uibutton(app.GridLayout, 'push');
            app.FormoreinformationsaboutBernsleyFernButton.ButtonPushedFcn = createCallbackFcn(app, @FormoreinformationsaboutBernsleyFernButtonPushed, true);
            app.FormoreinformationsaboutBernsleyFernButton.Text = {'For more informations'; ' about BernsleyFern'};
            app.FormoreinformationsaboutMandelbrotsetButton = uibutton(app.GridLayout, 'push');
            app.FormoreinformationsaboutMandelbrotsetButton.ButtonPushedFcn = createCallbackFcn(app, @FormoreinformationsaboutMandelbrotsetButtonPushed, true);
            app.FormoreinformationsaboutMandelbrotsetButton.Text = {'For more informations '; 'about Mandelbrot set'};
            app.FormoreinformationsaboutJuliasetButton = uibutton(app.GridLayout, 'push');
            app.FormoreinformationsaboutJuliasetButton.ButtonPushedFcn = createCallbackFcn(app, @FormoreinformationsaboutJuliasetButtonPushed, true);
            app.FormoreinformationsaboutJuliasetButton.Text = {'For more informations '; 'about Julia set'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FractalMenuApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end