classdef JuliaSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        animateButton                   matlab.ui.control.Button
        InterestingfunctionsDropDown    matlab.ui.control.DropDown
        InterestingfunctionsDropDownLabel  matlab.ui.control.Label
        tmaxEditField                   matlab.ui.control.NumericEditField
        tmaxEditFieldLabel              matlab.ui.control.Label
        tminEditField                   matlab.ui.control.NumericEditField
        tminEditFieldLabel              matlab.ui.control.Label
        functiontocreatecpointstoanimateEditField  matlab.ui.control.EditField
        functiontocreatecpointstoanimateEditFieldLabel  matlab.ui.control.Label
        SaveanimationButton             matlab.ui.control.Button
        SaveimageButton                 matlab.ui.control.Button
        SettingsPanel                   matlab.ui.container.Panel
        xresolutionEditField            matlab.ui.control.NumericEditField
        xresolutionEditFieldLabel       matlab.ui.control.Label
        yresolutionEditField            matlab.ui.control.NumericEditField
        yresolutionEditFieldLabel       matlab.ui.control.Label
        ymaxEditField                   matlab.ui.control.NumericEditField
        ymaxEditFieldLabel              matlab.ui.control.Label
        yminEditField                   matlab.ui.control.NumericEditField
        yminEditFieldLabel              matlab.ui.control.Label
        xmaxEditField                   matlab.ui.control.NumericEditField
        xmaxEditFieldLabel              matlab.ui.control.Label
        xminEditField                   matlab.ui.control.NumericEditField
        xminEditFieldLabel              matlab.ui.control.Label
        InterestingpointsDropDown       matlab.ui.control.DropDown
        InterestingpointsDropDownLabel  matlab.ui.control.Label
        c_ImEditField                   matlab.ui.control.NumericEditField
        c_ImEditFieldLabel              matlab.ui.control.Label
        c_ReEditField                   matlab.ui.control.NumericEditField
        c_ReEditFieldLabel              matlab.ui.control.Label
        maxiterationsEditField          matlab.ui.control.NumericEditField
        maxiterationsLabel              matlab.ui.control.Label
        DefaultoptionsButton            matlab.ui.control.Button
        PlotJuliasetButton              matlab.ui.control.Button
        ReturntomenuButton              matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        %Function generating Julia set
        function GenerateJuliaSet(app, xmin, xmax, ymin, ymax, Nx, Ny, maxIter, c)
            x=linspace(xmin,xmax,Nx);
            y=linspace(ymin,ymax,Ny);
            [X, Y] = meshgrid(x, y);
            Z = X + 1i * Y; 

            iterationCount = zeros(size(Z));
            M = ones(size(Z), 'logical'); 

            for n = 1:maxIter
                Z(M) = Z(M).^2 + c;
                escaped = abs(Z) > 2; 
                iterationCount(M & escaped) = n; 
                M(M & escaped) = false; 
                if ~any(M, 'all') 
                    break;
                end
            end

    iterationCount(iterationCount == 0) = maxIter;
            imagesc(app.UIAxes, x, y, iterationCount);
            axis(app.UIAxes, 'equal');
            axis(app.UIAxes, 'off');
            colormap(app.UIAxes, hot);
            drawnow;
            
        end
        %Function saving Julia set gif
        function SaveJuliaSetGIF(app, filename)
            xmin = app.xminEditField.Value;
            xmax = app.xmaxEditField.Value;
            ymin = app.yminEditField.Value;
            ymax = app.ymaxEditField.Value;
            Nx = app.xresolutionEditField.Value;
            Ny = app.yresolutionEditField.Value;
            maxIter = app.maxiterationsEditField.Value;
            for t =[app.tminEditField.Value:0.05:app.tmaxEditField.Value]
                c=eval(app.functiontocreatecpointstoanimateEditField.Value);
                app.GenerateJuliaSet(xmin, xmax, ymin, ymax, Nx, Ny, maxIter,c);

                frame = getframe(app.UIAxes);
                img = frame2im(frame);
                [imind, cm] = rgb2ind(img, 256);

                if t == app.tminEditField.Value
                    imwrite(imind, cm, filename, 'gif', 'Loopcount', inf, 'DelayTime', 0.1);
                else
                    imwrite(imind, cm, filename, 'gif', 'WriteMode', 'append', 'DelayTime', 0.1);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReturntomenuButton
        function ReturntomenuButtonPushed(app, event)
            %Function returing to Fractal menu app
            FractalMenuApp; 
            delete(app.UIFigure);
        end

        % Button pushed function: PlotJuliasetButton
        function PlotJuliasetButtonPushed(app, event)
            %Function generating Julia set with selected values
            xmin = app.xminEditField.Value;
            xmax = app.xmaxEditField.Value;
            ymin = app.yminEditField.Value;
            ymax = app.ymaxEditField.Value;
            Nx = app.xresolutionEditField.Value;
            Ny = app.yresolutionEditField.Value;
            maxIter = app.maxiterationsEditField.Value;
            c=app.c_ReEditField.Value+1i*app.c_ImEditField.Value;
            app.GenerateJuliaSet(xmin, xmax, ymin, ymax, Nx, Ny, maxIter,c);
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            %Function saving Julia set
            [file, path] = uiputfile({'*.png';'*.jpg';'*.pdf';'*.fig'}, 'Zapisz wykres jako');
            if isequal(file, 0) || isequal(path, 0)
                return;
            else
                filename = fullfile(path, file);
                try
                    exportgraphics(app.UIAxes, filename);
                catch
                    saveas(app.UIAxes, filename);
                end
            end
        end

        % Button pushed function: animateButton
        function animateButtonPushed(app, event)
            %Function generating an animation of Julia set
            xmin = app.xminEditField.Value;
            xmax = app.xmaxEditField.Value;
            ymin = app.yminEditField.Value;
            ymax = app.ymaxEditField.Value;
            Nx = app.xresolutionEditField.Value;
            Ny = app.yresolutionEditField.Value;
            maxIter = app.maxiterationsEditField.Value;
            for t =[app.tminEditField.Value:0.05:app.tmaxEditField.Value]
                c=eval(app.functiontocreatecpointstoanimateEditField.Value);
                app.GenerateJuliaSet(xmin, xmax, ymin, ymax, Nx, Ny, maxIter,c);
            end
        end

        % Value changed function: InterestingfunctionsDropDown
        function InterestingfunctionsDropDownValueChanged(app, event)
            %Function setting values to a function to animate 
            value = app.InterestingfunctionsDropDown.Value;
            switch value
                case 'Circle'
                    app.functiontocreatecpointstoanimateEditField.Value = '0.7885*exp(t*1i)'; 
                    app.tminEditField.Value=-pi;
                    app.tmaxEditField.Value=pi;
                case 'Line'
                    app.functiontocreatecpointstoanimateEditField.Value = '(t-2)+(t-1)*1i';
                    app.tminEditField.Value=0;
                    app.tmaxEditField.Value=2;
                case 'Spiral'
                    app.functiontocreatecpointstoanimateEditField.Value = '(0.5+t/(2*pi))*exp(t*1i)';
                    app.tminEditField.Value=-pi;
                    app.tmaxEditField.Value=pi;
                case 'Heart shape'
                    app.functiontocreatecpointstoanimateEditField.Value ='sin(t).^3 +1i*(13*cos(t)-5*cos(2*t)-2*cos(3*t)-cos(4*t))/16';
                    app.tminEditField.Value=-pi;
                    app.tmaxEditField.Value=pi;
            end
        end

        % Value changed function: InterestingpointsDropDown
        function InterestingpointsDropDownValueChanged(app, event)
            %Function setting values to aesthetically pleasing Julia sets
            value = app.InterestingpointsDropDown.Value;
            switch value
                case '-0.8+0.156*1i'
                    app.c_ReEditField.Value=-0.8;
                    app.c_ImEditField.Value=0.156;
                case '-0.4+0.6*1i'
                    app.c_ReEditField.Value=-0.4;
                    app.c_ImEditField.Value=0.6;
                case '0.285 + 0.01*1i'
                    app.c_ReEditField.Value=0.285;
                    app.c_ImEditField.Value=0.01;
                case '−0.70176 − 0.3842*1i'
                    app.c_ReEditField.Value=-0.70176;
                    app.c_ImEditField.Value=-0.3842;
                case '0.4 + 0.4*1i'
                    app.c_ReEditField.Value=0.4;
                    app.c_ImEditField.Value=0.4;
                    
            end
        end

        % Button pushed function: SaveanimationButton
        function SaveanimationButtonPushed(app, event)
            %Function saving a gif
            [file, path] = uiputfile('*.gif', 'Save animation as a GIF');
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            filename = fullfile(path, file);
            app.SaveJuliaSetGIF(filename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Julia Set')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ReturntomenuButton = uibutton(app.GridLayout, 'push');
            app.ReturntomenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntomenuButtonPushed, true);
            app.ReturntomenuButton.Text = 'Return to menu';
            app.PlotJuliasetButton = uibutton(app.GridLayout, 'push');
            app.PlotJuliasetButton.ButtonPushedFcn = createCallbackFcn(app, @PlotJuliasetButtonPushed, true);
            app.PlotJuliasetButton.Text = 'Plot Julia set';
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.DefaultoptionsButton = uibutton(app.SettingsPanel, 'push');
            app.DefaultoptionsButton.Text = 'Default options';
            app.maxiterationsLabel = uilabel(app.SettingsPanel);
            app.maxiterationsLabel.Text = {'max '; 'iterations'};
            app.maxiterationsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.maxiterationsEditField.ValueDisplayFormat = '%.0f';
            app.maxiterationsEditField.Value = 100;
            app.c_ReEditFieldLabel = uilabel(app.SettingsPanel);
            app.c_ReEditFieldLabel.Text = 'c_Re';
            app.c_ReEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.c_ReEditField.Value = -0.8;
            app.c_ImEditFieldLabel = uilabel(app.SettingsPanel);
            app.c_ImEditFieldLabel.Text = 'c_Im';
            app.c_ImEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.c_ImEditField.Value = 0.156;
            app.InterestingpointsDropDownLabel = uilabel(app.SettingsPanel);
            app.InterestingpointsDropDownLabel.Text = 'Interesting points';
            app.InterestingpointsDropDown = uidropdown(app.SettingsPanel);
            app.InterestingpointsDropDown.Items = {'-0.8+0.156*1i', '-0.4+0.6*1i', '0.285 + 0.01*1i', '−0.70176 − 0.3842*1i', '0.4 + 0.4*1i'};
            app.InterestingpointsDropDown.ValueChangedFcn = createCallbackFcn(app, @InterestingpointsDropDownValueChanged, true);
            app.InterestingpointsDropDown.Value = '-0.8+0.156*1i';
            app.xminEditFieldLabel = uilabel(app.SettingsPanel);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xminEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.xminEditField.Value = -2;
            app.xmaxEditFieldLabel = uilabel(app.SettingsPanel);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.xmaxEditField.Value = 2;
            app.yminEditFieldLabel = uilabel(app.SettingsPanel);
            app.yminEditFieldLabel.Text = 'ymin';
            app.yminEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.yminEditField.Value = -2;
            app.ymaxEditFieldLabel = uilabel(app.SettingsPanel);
            app.ymaxEditFieldLabel.Text = 'ymax';
            app.ymaxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.ymaxEditField.Value = 2;
            app.yresolutionEditFieldLabel = uilabel(app.SettingsPanel);
            app.yresolutionEditFieldLabel.Text = 'y resolution';
            app.yresolutionEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.yresolutionEditField.ValueDisplayFormat = '%.0f';
            app.yresolutionEditField.Value = 1000;
            app.xresolutionEditFieldLabel = uilabel(app.SettingsPanel);
            app.xresolutionEditFieldLabel.Text = 'x resolution';
            app.xresolutionEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.xresolutionEditField.ValueDisplayFormat = '%.0f';
            app.xresolutionEditField.Value = 1000;
            app.SaveimageButton = uibutton(app.GridLayout, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Text = 'Save image';
            app.SaveanimationButton = uibutton(app.GridLayout, 'push');
            app.SaveanimationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveanimationButtonPushed, true);
            app.SaveanimationButton.Text = 'Save animation';
            app.Panel_2 = uipanel(app.GridLayout);
            app.functiontocreatecpointstoanimateEditFieldLabel = uilabel(app.Panel_2);
            app.functiontocreatecpointstoanimateEditFieldLabel.Text = 'function to create c points to animate';
            app.functiontocreatecpointstoanimateEditField = uieditfield(app.Panel_2, 'text');
            app.functiontocreatecpointstoanimateEditField.Value = '0.7885*exp(t*1i)';
            app.tminEditFieldLabel = uilabel(app.Panel_2);
            app.tminEditFieldLabel.Text = 'tmin';
            app.tminEditField = uieditfield(app.Panel_2, 'numeric');
            app.tminEditField.Value = -3.1415;
            app.tmaxEditFieldLabel = uilabel(app.Panel_2);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEditField = uieditfield(app.Panel_2, 'numeric');
            app.tmaxEditField.Limits = [0 Inf];
            app.tmaxEditField.Value = 3.1415;
            app.InterestingfunctionsDropDownLabel = uilabel(app.Panel_2);
            app.InterestingfunctionsDropDownLabel.Text = 'Interesting functions';
            app.InterestingfunctionsDropDown = uidropdown(app.Panel_2);
            app.InterestingfunctionsDropDown.Items = {'Circle', 'Line', 'Spiral', 'Heart shape'};
            app.InterestingfunctionsDropDown.ValueChangedFcn = createCallbackFcn(app, @InterestingfunctionsDropDownValueChanged, true);
            app.InterestingfunctionsDropDown.Value = 'Circle';
            app.animateButton = uibutton(app.Panel_2, 'push');
            app.animateButton.ButtonPushedFcn = createCallbackFcn(app, @animateButtonPushed, true);
            app.animateButton.Text = 'animate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JuliaSet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end