classdef MandelbrotSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        SaveanimationasagifButton      matlab.ui.control.Button
        SaveimageButton                matlab.ui.control.Button
        Panel2                         matlab.ui.container.Panel
        resolutionEditField            matlab.ui.control.NumericEditField
        resolutionEditFieldLabel       matlab.ui.control.Label
        StopthezoomButton              matlab.ui.control.Button
        ZoomspeedEditField             matlab.ui.control.NumericEditField
        ZoomspeedEditFieldLabel        matlab.ui.control.Label
        maxiterationsEditField_2       matlab.ui.control.NumericEditField
        maxiterationsEditField_2Label  matlab.ui.control.Label
        InterestingpointsDropDown      matlab.ui.control.DropDown
        InterestingpointsLabel         matlab.ui.control.Label
        ZoomintothispointButton        matlab.ui.control.Button
        ImEditField                    matlab.ui.control.NumericEditField
        ImEditFieldLabel               matlab.ui.control.Label
        ReEditField                    matlab.ui.control.NumericEditField
        ReLabel                        matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        DefaultoptionsButton           matlab.ui.control.Button
        maxiterationsEditField         matlab.ui.control.NumericEditField
        maxiterationsEditFieldLabel    matlab.ui.control.Label
        yresolutionEditField           matlab.ui.control.NumericEditField
        yresolutionEditFieldLabel      matlab.ui.control.Label
        xresolutionEditField           matlab.ui.control.NumericEditField
        xresolutionEditFieldLabel      matlab.ui.control.Label
        ymaxEditField                  matlab.ui.control.NumericEditField
        ymaxEditFieldLabel             matlab.ui.control.Label
        yminEditField                  matlab.ui.control.NumericEditField
        yminEditFieldLabel             matlab.ui.control.Label
        xmaxEditField                  matlab.ui.control.NumericEditField
        xmaxEditFieldLabel             matlab.ui.control.Label
        xminEditField                  matlab.ui.control.NumericEditField
        xminEditFieldLabel             matlab.ui.control.Label
        GenerateMandelbrotsetButton    matlab.ui.control.Button
        ReturntomenuButton             matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ZoomStop=false 
    end
    
    methods (Access = private)
        %Function generating Mandelbrot set
        function GenerateMandelbrotset(app, xmin, xmax, ymin, ymax, Nx, Ny, maxIter)
            x = linspace(xmin, xmax, Nx);
            y = linspace(ymin, ymax, Ny);
            [X, Y] = meshgrid(x, y);
            C = X + 1i * Y;

            Z = zeros(size(C));
            iterationCount = zeros(size(C));
            M = ones(size(C), 'logical');
            for n = 1:maxIter
                Z(M) = Z(M).^2 + C(M);
                escaped = abs(Z) > 2;
                iterationCount(M & escaped) = n;
                M(M & escaped) = false;
                if ~any(M, 'all')
                    break;
                end
            end
        

            iterationCount(iterationCount == 0) = maxIter;
            imagesc(app.UIAxes, x, y, iterationCount);
            axis(app.UIAxes, 'equal');
            axis(app.UIAxes, 'off');
            colormap(app.UIAxes, hot);
            drawnow;
        end
        %Function saving a gif of Mandelbrot set
        function SaveMandelbrotGIF(app, filename)
            Re = app.ReEditField.Value;
            Im = app.ImEditField.Value;
            Nx = app.xresolutionEditField.Value;
            Ny = app.yresolutionEditField.Value;
            maxIter = app.maxiterationsEditField.Value;
            numFrames = app.maxiterationsEditField_2.Value; 
            xrange = app.xmaxEditField.Value - app.xminEditField.Value;
            yrange = app.ymaxEditField.Value - app.yminEditField.Value;

            for i = 1:numFrames
                scale = app.ZoomspeedEditField.Value^i;
                current_xrange = xrange / scale;
                current_yrange = yrange / scale;
                xmin = Re - current_xrange / 2;
                xmax = Re + current_xrange / 2;
                ymin = Im - current_yrange / 2;
                ymax = Im + current_yrange / 2;
                app.GenerateMandelbrotset(xmin, xmax, ymin, ymax, Nx, Ny, maxIter);
                frame = getframe(app.UIAxes);
                img = frame2im(frame);
                [imind, cm] = rgb2ind(img, 256);
                if i == 1
                    imwrite(imind, cm, filename, 'gif', 'Loopcount', inf, 'DelayTime', 0.1);
                else
                    imwrite(imind, cm, filename, 'gif', 'WriteMode', 'append', 'DelayTime', 0.1);
                end
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateMandelbrotsetButton
        function GenerateMandelbrotsetButtonPushed(app, event)
            %Function generating Mandelbrot set with selected values
            xmin = app.xminEditField.Value;
            xmax = app.xmaxEditField.Value;
            ymin = app.yminEditField.Value;
            ymax = app.ymaxEditField.Value;
            Nx = app.xresolutionEditField.Value;
            Ny = app.yresolutionEditField.Value;
            maxIter = app.maxiterationsEditField.Value;
            app.GenerateMandelbrotset(xmin, xmax, ymin, ymax, Nx, Ny, maxIter);
        end

        % Button pushed function: DefaultoptionsButton
        function DefaultoptionsButtonPushed(app, event)
            %Function setting the defult values
            app.xminEditField.Value=-2;
            app.xmaxEditField.Value=1;
            app.yminEditField.Value=-1.5;
            app.ymaxEditField.Value=1.5;
            app.xresolutionEditField.Value=1000;
            app.yresolutionEditField.Value=1000;
            app.maxiterationsEditField.Value=100;
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            %Function saving the image
            [file, path] = uiputfile({'*.png';'*.jpg';'*.pdf';'*.fig'}, 'Zapisz wykres jako');
            if isequal(file, 0) || isequal(path, 0)
                return;
            else
                filename = fullfile(path, file);
                try
                    exportgraphics(app.UIAxes, filename);
                catch
                    saveas(app.UIAxes, filename);
                end
            end
        end

        % Button pushed function: ReturntomenuButton
        function ReturntomenuButtonPushed(app, event)
            %Function returing to Fractal manu app
            FractalMenuApp; 
            delete(app.UIFigure);
        end

        % Button pushed function: ZoomintothispointButton
        function ZoomintothispointButtonPushed(app, event)
            %Function zooming into selected point in Mandelbrot set
            Re = app.ReEditField.Value;
            Im = app.ImEditField.Value;
            Nx = app.resolutionEditField.Value;
            Ny = app.resolutionEditField.Value;
            maxIter = 100;
            xrange = app.xmaxEditField.Value - app.xminEditField.Value;
            yrange = app.ymaxEditField.Value - app.yminEditField.Value;
        
            for i = 1:app.maxiterationsEditField_2.Value
                scale = app.ZoomspeedEditField.Value^i;
                current_xrange = xrange / scale;
                current_yrange = yrange / scale;
                xmin = Re - current_xrange / 2;
                xmax = Re + current_xrange / 2;
                ymin = Im - current_yrange / 2;
                ymax = Im + current_yrange / 2;
                if app.ZoomStop
                    app.ZoomStop=false;
                    break
                end
                app.GenerateMandelbrotset(xmin, xmax, ymin, ymax, Nx, Ny, maxIter);
                % pause(0.01);
            end
        

        end

        % Value changed function: InterestingpointsDropDown
        function InterestingpointsDropDownValueChanged(app, event)
            %Function that cahnges values to interesting points selected in drop down                        
            disp('Funkcja została wywołana');
            value = app.InterestingpointsDropDown.Value;
            switch value
                case 'Seahorse Valley'
                    app.ReEditField.Value = -0.743643887037151;  
                    app.ImEditField.Value = 0.131825904205330;
                    app.maxiterationsEditField_2.Value=100;
        
                case 'Elephant Valley'
                    app.ReEditField.Value = 0.255; 
                    app.ImEditField.Value = 0.000000000000000;
                    app.maxiterationsEditField_2.Value=80;
       
        
                case 'Misiurewicz point'
                    app.ReEditField.Value = -0.101096363845875;
                    app.ImEditField.Value = 0.956286510809140;
                    app.maxiterationsEditField_2.Value=100;
        
                case 'Baby Mandelbrots'
                    app.ReEditField.Value = -1.250660360019296; 
                    app.ImEditField.Value = 0.020120719850672;
                    app.maxiterationsEditField_2.Value=100;
        
                case 'Cardioids'
                    app.ReEditField.Value = -0.775683769238462;
                    app.ImEditField.Value = 0.136467370942562;
                    app.maxiterationsEditField_2.Value=100;
            end
            disp(['ReEditField.Value = ', num2str(app.ReEditField.Value, 15)]);
            disp(['ImEditField.Value = ', num2str(app.ImEditField.Value, 15)]);
        

        end

        % Button pushed function: SaveanimationasagifButton
        function SaveanimationasagifButtonPushed(app, event)
            %Function saving a gif of Mandelbrot set
            [file, path] = uiputfile('*.gif', 'Save animation as a GIF');
            if isequal(file, 0) || isequal(path, 0)
                return;
            end
            filename = fullfile(path, file);
            app.SaveMandelbrotGIF(filename);
        end

        % Button pushed function: StopthezoomButton
        function StopthezoomButtonPushed(app, event)
            %Function stopping the Mandelbrot set zoom
            app.ZoomStop=true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Mandelbrot set ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ReturntomenuButton = uibutton(app.GridLayout, 'push');
            app.ReturntomenuButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntomenuButtonPushed, true);
            app.ReturntomenuButton.Text = 'Return to menu';
            app.GenerateMandelbrotsetButton = uibutton(app.GridLayout, 'push');
            app.GenerateMandelbrotsetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMandelbrotsetButtonPushed, true);
            app.GenerateMandelbrotsetButton.Text = 'Generate Mandelbrot set';
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.xminEditFieldLabel = uilabel(app.Panel);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xminEditField = uieditfield(app.Panel, 'numeric');
            app.xminEditField.Value = -2;
            app.xmaxEditFieldLabel = uilabel(app.Panel);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.Panel, 'numeric');
            app.xmaxEditField.Value = 1;
            app.yminEditFieldLabel = uilabel(app.Panel);
            app.yminEditFieldLabel.Text = 'ymin';
            app.yminEditField = uieditfield(app.Panel, 'numeric');
            app.yminEditField.Value = -1.5;
            app.ymaxEditFieldLabel = uilabel(app.Panel);
            app.ymaxEditFieldLabel.Text = 'ymax';
            app.ymaxEditField = uieditfield(app.Panel, 'numeric');
            app.ymaxEditField.Value = 1.5;
            app.xresolutionEditFieldLabel = uilabel(app.Panel);
            app.xresolutionEditFieldLabel.Text = 'x resolution';
            app.xresolutionEditField = uieditfield(app.Panel, 'numeric');
            app.xresolutionEditField.ValueDisplayFormat = '%.0f';
            app.xresolutionEditField.Value = 1000;
            app.yresolutionEditFieldLabel = uilabel(app.Panel);
            app.yresolutionEditFieldLabel.Text = 'y resolution';
            app.yresolutionEditField = uieditfield(app.Panel, 'numeric');
            app.yresolutionEditField.ValueDisplayFormat = '%.0f';
            app.yresolutionEditField.Value = 1000;
            app.maxiterationsEditFieldLabel = uilabel(app.Panel);
            app.maxiterationsEditFieldLabel.Text = 'max iterations';
            app.maxiterationsEditField = uieditfield(app.Panel, 'numeric');
            app.maxiterationsEditField.ValueDisplayFormat = '%.0f';
            app.maxiterationsEditField.Value = 100;
            app.DefaultoptionsButton = uibutton(app.Panel, 'push');
            app.DefaultoptionsButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultoptionsButtonPushed, true);
            app.DefaultoptionsButton.Text = 'Default options';
            app.Panel2 = uipanel(app.GridLayout);
            app.Panel2.Title = 'Panel2';
            app.ReLabel = uilabel(app.Panel2);
            app.ReLabel.Text = 'Re  ';
            app.ReEditField = uieditfield(app.Panel2, 'numeric');
            app.ReEditField.Value = -0.101096363845875;
            app.ImEditFieldLabel = uilabel(app.Panel2);
            app.ImEditFieldLabel.Text = 'Im';
            app.ImEditField = uieditfield(app.Panel2, 'numeric');
            app.ImEditField.Value = -0.95628651080914;
            app.ZoomintothispointButton = uibutton(app.Panel2, 'push');
            app.ZoomintothispointButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomintothispointButtonPushed, true);
            app.ZoomintothispointButton.Text = 'Zoom into this point';
            app.InterestingpointsLabel = uilabel(app.Panel2);
            app.InterestingpointsLabel.Text = 'Interesting points';
            app.InterestingpointsDropDown = uidropdown(app.Panel2);
            app.InterestingpointsDropDown.Items = {'Seahorse Valley', 'Elephant Valley', 'Misiurewicz point', 'Baby Mandelbrots', 'Cardioids'};
            app.InterestingpointsDropDown.ValueChangedFcn = createCallbackFcn(app, @InterestingpointsDropDownValueChanged, true);
            app.InterestingpointsDropDown.Value = 'Misiurewicz point';
            app.maxiterationsEditField_2Label = uilabel(app.Panel2);
            app.maxiterationsEditField_2Label.Text = 'max iterations';
            app.maxiterationsEditField_2 = uieditfield(app.Panel2, 'numeric');
            app.maxiterationsEditField_2.Limits = [1 1000];
            app.maxiterationsEditField_2.ValueDisplayFormat = '%.0f';
            app.maxiterationsEditField_2.Value = 100;
            app.ZoomspeedEditFieldLabel = uilabel(app.Panel2);
            app.ZoomspeedEditFieldLabel.Text = 'Zoom  speed';
            app.ZoomspeedEditField = uieditfield(app.Panel2, 'numeric');
            app.ZoomspeedEditField.Limits = [1.01 2];
            app.ZoomspeedEditField.Value = 1.1;
            app.StopthezoomButton = uibutton(app.Panel2, 'push');
            app.StopthezoomButton.ButtonPushedFcn = createCallbackFcn(app, @StopthezoomButtonPushed, true);
            app.StopthezoomButton.Text = 'Stop the zoom';
            app.resolutionEditFieldLabel = uilabel(app.Panel2);
            app.resolutionEditFieldLabel.Text = 'resolution';
            app.resolutionEditField = uieditfield(app.Panel2, 'numeric');
            app.resolutionEditField.Value = 400;
            app.SaveimageButton = uibutton(app.GridLayout, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Text = 'Save image';
            app.SaveanimationasagifButton = uibutton(app.GridLayout, 'push');
            app.SaveanimationasagifButton.ButtonPushedFcn = createCallbackFcn(app, @SaveanimationasagifButtonPushed, true);
            app.SaveanimationasagifButton.Text = 'Save animation as a gif';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MandelbrotSet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end